classdef ui_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        UITable                     matlab.ui.control.Table
        AccelerationLiveValuePanel  matlab.ui.container.Panel
        AccelerationLiveValueGridLayout  matlab.ui.container.GridLayout
        AccelerationUnitsLabel      matlab.ui.control.Label
        AccelerationLiveValueLabel  matlab.ui.control.Label
        SpeedLiveValuePanel         matlab.ui.container.Panel
        SpeedLiveValueGridLayout    matlab.ui.container.GridLayout
        SpeedUnitsLabel             matlab.ui.control.Label
        SpeedLiveValueLabel         matlab.ui.control.Label
        InformationPanel            matlab.ui.container.Panel
        InformationPanelGridLayout  matlab.ui.container.GridLayout
        StatusPanel                 matlab.ui.container.Panel
        SessionInfoGridLayoud_2     matlab.ui.container.GridLayout
        ERSValueLabel               matlab.ui.control.Label
        DRSActiveLabel              matlab.ui.control.Label
        FuelPercentageLabel         matlab.ui.control.Label
        TyreLifeLapsLabel           matlab.ui.control.Label
        TyreHealthLabel             matlab.ui.control.Label
        ERSLabel                    matlab.ui.control.Label
        DRSLabel                    matlab.ui.control.Label
        FuelLabel                   matlab.ui.control.Label
        TyreLifeLabel               matlab.ui.control.Label
        TyreLabel                   matlab.ui.control.Label
        SessionInfoPanel            matlab.ui.container.Panel
        SessionInfoGridLayoud       matlab.ui.container.GridLayout
        PositionValueLabel          matlab.ui.control.Label
        GapTimeLabel                matlab.ui.control.Label
        LapTimeValueLabel           matlab.ui.control.Label
        LapCountLabel               matlab.ui.control.Label
        TrainingLabel               matlab.ui.control.Label
        PositionLabel               matlab.ui.control.Label
        GapLabel                    matlab.ui.control.Label
        LapTimeLabel                matlab.ui.control.Label
        LapLabel                    matlab.ui.control.Label
        SessionLabel                matlab.ui.control.Label
        DriverInfoPanel             matlab.ui.container.Panel
        DriverInfoGridLayout        matlab.ui.container.GridLayout
        DriverNumberLabel           matlab.ui.control.Label
        TeamLabel                   matlab.ui.control.Label
        JohnSanburnLabel            matlab.ui.control.Label
        ToolbarPanel                matlab.ui.container.Panel
        ToolbarPanelGridLayout      matlab.ui.container.GridLayout
        LoadButton                  matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
        SpeedGraph                  matlab.ui.control.UIAxes
        AccelerationGraph           matlab.ui.control.UIAxes
    end

    
    % User defined variables
    properties (Access = private)
        AccelerationData = []
        SpeedData = []
        TimeData = []

        CurrentAcceleration = 0
        Timer

    end
    
    methods (Access = private)
        
        function updatePlot(app)
            app.CurrentAcceleration = app.CurrentAcceleration * 0.92 + (rand() - 0.5) * 2.0;
            app.CurrentAcceleration = max(-20, min(20, app.CurrentAcceleration));
            newAcceleration = app.CurrentAcceleration;

            newTime = toc;
            if isempty(app.SpeedData)
                newSpeed = 0;
            else
                newSpeed = app.SpeedData(end) + app.CurrentAcceleration * 0.05;
            end

            app.TimeData = [app.TimeData, newTime];
            app.AccelerationData = [app.AccelerationData, newAcceleration];
            app.SpeedData = [app.SpeedData, newSpeed];

            plot(app.AccelerationGraph, app.TimeData, app.AccelerationData);
            plot(app.SpeedGraph, app.TimeData, app.SpeedData);

            app.SpeedLiveValueLabel.Text = sprintf('%.1f', newSpeed);
            app.AccelerationLiveValueLabel.Text = sprintf('%.2f', app.CurrentAcceleration);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Getting stuff I couldn't change in the component browser
            app.SpeedGraph.Title.Color = [0.67 0.8 0.93];
            app.AccelerationGraph.Title.Color = [0.67 0.8 0.93];

            % Setting for timer
            app.Timer = timer('ExecutionMode', 'fixedRate', ...
                              'Period', 0.05, ...
                              'TimerFcn', @(~,~) app.updatePlot());

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.Timer);
            delete(app.Timer);
            delete(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if strcmp(app.Timer.Running, 'off')
                app.AccelerationData = [];
                app.SpeedData = [];
                app.TimeData = [];
                app.CurrentAcceleration = 0;

                start(app.Timer)
                tic
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if strcmp(app.Timer.Running, 'on')
                stop(app.Timer);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AccelerationGraph = uiaxes(app.GridLayout);
            title(app.AccelerationGraph, 'Acceleration Graph')
            xlabel(app.AccelerationGraph, 'Time')
            ylabel(app.AccelerationGraph, 'Acceleration')
            zlabel(app.AccelerationGraph, 'Z')
            app.SpeedGraph = uiaxes(app.GridLayout);
            title(app.SpeedGraph, 'Speed Graph')
            xlabel(app.SpeedGraph, 'Time')
            ylabel(app.SpeedGraph, 'Speed')
            zlabel(app.SpeedGraph, 'Z')
            app.ToolbarPanel = uipanel(app.GridLayout);
            app.ToolbarPanel.Title = 'Toolbar';
            app.ToolbarPanelGridLayout = uigridlayout(app.ToolbarPanel);
            app.RunButton = uibutton(app.ToolbarPanelGridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = '▶Run';
            app.StopButton = uibutton(app.ToolbarPanelGridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = '■Stop';
            app.LoadButton = uibutton(app.ToolbarPanelGridLayout, 'push');
            app.LoadButton.Text = 'Load';
            app.InformationPanel = uipanel(app.GridLayout);
            app.InformationPanel.Title = 'Information';
            app.InformationPanelGridLayout = uigridlayout(app.InformationPanel);
            app.DriverInfoPanel = uipanel(app.InformationPanelGridLayout);
            app.DriverInfoPanel.Title = 'Driver Info';
            app.DriverInfoGridLayout = uigridlayout(app.DriverInfoPanel);
            app.JohnSanburnLabel = uilabel(app.DriverInfoGridLayout);
            app.JohnSanburnLabel.Text = 'John Sanburn';
            app.TeamLabel = uilabel(app.DriverInfoGridLayout);
            app.TeamLabel.Text = 'LiveTelemetry';
            app.DriverNumberLabel = uilabel(app.DriverInfoGridLayout);
            app.DriverNumberLabel.Text = '#67';
            app.SessionInfoPanel = uipanel(app.InformationPanelGridLayout);
            app.SessionInfoPanel.Title = 'Session Info';
            app.SessionInfoGridLayoud = uigridlayout(app.SessionInfoPanel);
            app.SessionLabel = uilabel(app.SessionInfoGridLayoud);
            app.SessionLabel.Text = 'Session';
            app.LapLabel = uilabel(app.SessionInfoGridLayoud);
            app.LapLabel.Text = 'Lap';
            app.LapTimeLabel = uilabel(app.SessionInfoGridLayoud);
            app.LapTimeLabel.Text = 'Lap Time';
            app.GapLabel = uilabel(app.SessionInfoGridLayoud);
            app.GapLabel.Text = 'Gap';
            app.PositionLabel = uilabel(app.SessionInfoGridLayoud);
            app.PositionLabel.Text = 'Position';
            app.TrainingLabel = uilabel(app.SessionInfoGridLayoud);
            app.TrainingLabel.Text = 'Training';
            app.LapCountLabel = uilabel(app.SessionInfoGridLayoud);
            app.LapCountLabel.Text = '1/1';
            app.LapTimeValueLabel = uilabel(app.SessionInfoGridLayoud);
            app.LapTimeValueLabel.Text = '1:23.456';
            app.GapTimeLabel = uilabel(app.SessionInfoGridLayoud);
            app.GapTimeLabel.Text = '+6.7s';
            app.PositionValueLabel = uilabel(app.SessionInfoGridLayoud);
            app.PositionValueLabel.Text = 'P9';
            app.StatusPanel = uipanel(app.InformationPanelGridLayout);
            app.StatusPanel.Title = 'Status';
            app.SessionInfoGridLayoud_2 = uigridlayout(app.StatusPanel);
            app.TyreLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.TyreLabel.Text = 'Tyre';
            app.TyreLifeLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.TyreLifeLabel.Text = 'Tyre Life';
            app.FuelLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.FuelLabel.Text = 'Fuel';
            app.DRSLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.DRSLabel.Text = 'DRS';
            app.ERSLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.ERSLabel.Text = 'ERS';
            app.TyreHealthLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.TyreHealthLabel.Text = 'Medium';
            app.TyreLifeLapsLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.TyreLifeLapsLabel.Text = '16 Laps';
            app.FuelPercentageLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.FuelPercentageLabel.Text = '67%';
            app.DRSActiveLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.DRSActiveLabel.Text = 'Active';
            app.ERSValueLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.ERSValueLabel.Text = '67 kJ';
            app.SpeedLiveValuePanel = uipanel(app.GridLayout);
            app.SpeedLiveValuePanel.Title = 'Speed Live Value';
            app.SpeedLiveValueGridLayout = uigridlayout(app.SpeedLiveValuePanel);
            app.SpeedLiveValueLabel = uilabel(app.SpeedLiveValueGridLayout);
            app.SpeedLiveValueLabel.Text = '0';
            app.SpeedUnitsLabel = uilabel(app.SpeedLiveValueGridLayout);
            app.SpeedUnitsLabel.Text = 'm/s';
            app.AccelerationLiveValuePanel = uipanel(app.GridLayout);
            app.AccelerationLiveValuePanel.Title = 'Acceleration Live Value';
            app.AccelerationLiveValueGridLayout = uigridlayout(app.AccelerationLiveValuePanel);
            app.AccelerationLiveValueLabel = uilabel(app.AccelerationLiveValueGridLayout);
            app.AccelerationLiveValueLabel.Text = '0';
            app.AccelerationUnitsLabel = uilabel(app.AccelerationLiveValueGridLayout);
            app.AccelerationUnitsLabel.Text = 'm/s²';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end